classdef SSDApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        FaultDetectionMulticlassLabel  matlab.ui.control.Label
        StartContinuousProcessButton   matlab.ui.control.Button
        FaultDetectionLamp             matlab.ui.control.Lamp
        FaultDetectionLampLabel        matlab.ui.control.Label
        StartAnalysisButton            matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        DataTab                        matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        DataVisualizationPanel         matlab.ui.container.Panel
        DataTable                      matlab.ui.control.Table
        UploaddataButton               matlab.ui.control.Button
        DataSpectrogramTab             matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        SignalSpectrogramPanel         matlab.ui.container.Panel
        ComponentsTab                  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        KeyFiguresPanel                matlab.ui.container.Panel
        VarianceLabel                  matlab.ui.control.Label
        VarianceTable                  matlab.ui.control.Table
        MeanLabel                      matlab.ui.control.Label
        MeanTable                      matlab.ui.control.Table
        NumberofComponentsEditField    matlab.ui.control.NumericEditField
        NumberofComponentsLabel        matlab.ui.control.Label
        SSDComponentsPanel             matlab.ui.container.Panel
        ComponentSpectrogramsTab       matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        SpectrogramperComponentPanel   matlab.ui.container.Panel
        AnalysisTab                    matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        DevelopmentofKeyFiguresPanel   matlab.ui.container.Panel
        SpectrogramAnalysisTab         matlab.ui.container.Tab
        DevelopmentofComponentSpectrogramsPanel  matlab.ui.container.Panel
        SettingsPanel                  matlab.ui.container.Panel
        ModelSwitch                    matlab.ui.control.RockerSwitch
        ModelSwitchLabel               matlab.ui.control.Label
        MonitoringTimeEditField        matlab.ui.control.NumericEditField
        MonitoringTimeminLabel         matlab.ui.control.Label
        ContinuousProcessLabel         matlab.ui.control.Label
        FaultDetectionLabel            matlab.ui.control.Label
        FaultDetectionSwitch           matlab.ui.control.Switch
        SwitchLabel                    matlab.ui.control.Label
        FillNaNCheckBox                matlab.ui.control.CheckBox
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SingularSpectrumDecompositionLabel  matlab.ui.control.Label
        PositionSlider                 matlab.ui.control.Slider
        PositionSlider_2Label          matlab.ui.control.Label
        StepsizeEditField              matlab.ui.control.NumericEditField
        StepsizeLabel                  matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        ZoominwindowCheckBox           matlab.ui.control.CheckBox
        VisualizationLabel             matlab.ui.control.Label
        WindowLabel                    matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        uploadedData % raw data that is uploaded
        analysisData % data that is currently used for the analysis
        analysisSsdComponents % calculated SSD components for the current window
        numOfComps_win % calculated number of components for the current window
        meanPComp_win % calculated mean per component for the current window
        varPComp_win % calculated variance per component for the current window
        numOfComps_sig % calculated number of components for the whole signal
        meanPComp_sig % calculated mean per component for the whole signal
        varPComp_sig % calculated variance per component for the whole signal
        stftPComp_win
        freqs_win
        stftPComp_sig
        freqs_sig
        faultDetectFlags % Description
    end
    
    methods (Access = private)
        
        function updateDataPlots(app)
            [m,~] = size(app.uploadedData);

            % create tiled layout for plots
            tllyData = tiledlayout(app.DataVisualizationPanel,1,1);
            duration = m-2 / app.SamplingFrequencyEditField.Value;
            timeVec = linspace(0, duration, m); 

            selected_column_index = app.DataTable.Selection;                
            start_idx = 1+ (app.PositionSlider.Value-1) * app.StepsizeEditField.Value;
            end_idx = start_idx + app.LengthEditField.Value-1;

            % if zoom function is off, mark the range with vertical lines
            if (~app.ZoominwindowCheckBox.Value)
                % plot the selected signal
                ax = nexttile(tllyData);
                plot(ax, timeVec, table2array(app.uploadedData(:,selected_column_index)));
                hold(ax, 'on');
                xline(ax, timeVec(start_idx), Color='red', LineWidth=0.7);
                xline(ax, timeVec(end_idx), Color='red', LineWidth=0.7);
                xlim(ax,[timeVec(1),timeVec(end)]);
                tllyData.TileSpacing = 'compact';
                tllyData.Padding = 'compact';
                xlabel(ax, 'Time (s)'); 
                ylabel(ax, 'Amplitude');
                hold(ax, 'off');
            else
                % plot data to analyze 
                [~,~] = size(app.analysisData);
                ax = nexttile(tllyData);
                plot(ax, timeVec(start_idx:end_idx), table2array(app.analysisData));
                xlim(ax,[timeVec(start_idx),timeVec(end_idx)]);
                xlabel(ax, 'Time (s)'); 
                ylabel(ax, 'Amplitude');
                tllyData.TileSpacing = 'compact';
                tllyData.Padding = 'compact';
            end
        end
        
        function calcSSDAndFigs(app)
            comps = SSD(table2array(app.analysisData),app.SamplingFrequencyEditField.Value, app.ThresholdEditField.Value);
            residual = table2array(app.analysisData) - sum(comps).';
            comps(size(comps,1)+1,:) = residual.';
            app.analysisSsdComponents = comps.';
            [app.numOfComps_win,app.meanPComp_win,app.varPComp_win,app.stftPComp_win,app.freqs_win] = keyFigsWindow(comps.', app.SamplingFrequencyEditField.Value, app.LengthEditField.Value);
        end
        
        function updateComponentPlotsAndFigues(app)
            % UPDATE PLOTS
            [~,n] = size(app.analysisSsdComponents);

            start_idx = 1+ (app.PositionSlider.Value-1) * app.StepsizeEditField.Value;
            end_idx = start_idx + app.LengthEditField.Value-1;

            num_datapoints = size(app.uploadedData,1);
            duration = num_datapoints-2 / app.SamplingFrequencyEditField.Value; 
            timeVec = linspace(0, duration, num_datapoints);
            xAxis = timeVec(start_idx:end_idx);

            ax = matlab.graphics.axis.Axes.empty();
            while(length(app.SSDComponentsPanel.Children)>0)
                delete(app.SSDComponentsPanel.Children(end))
            end

            plot_height = 0.4;
            tile_space = 0.2;
            offset = 0.05;

            app.SSDComponentsPanel.AutoResizeChildren = 'off';
            ax(1) = subplot(n,1,1,'Parent',app.SSDComponentsPanel);
            pos_og = get(ax(1), 'Position');
            left_og = pos_og(1);
            width_og = pos_og(3);

            %offset = bottom_og - height_og;
            new_position = [left_og, offset + (n-1)*plot_height+(n-1)*tile_space, width_og, plot_height];
            set(ax(1), 'Position', new_position);

            plot(ax(1), xAxis, app.analysisSsdComponents(:,1));
            xlabel(ax(1), 'Time (s)'); 
            ylabel(ax(1), 'Amplitude');
            xlim(ax(1),[xAxis(1),xAxis(end)]);
            subtitle(ax(1), 'Component 1');

            for (i=2:n)
                ax(i) = subplot(n,1,i,'Parent',app.SSDComponentsPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                plot(ax(i), xAxis, app.analysisSsdComponents(:,i));
                xlabel(ax(i), 'Time (s)'); 
                ylabel(ax(i), 'Amplitude');
                xlim(ax(i),[xAxis(1),xAxis(end)]);
                if i==n
                    subtitle(ax(i), 'Residual')
                else
                    subtitle(ax(i), ['Component ',num2str(i)]);
                end
            end

            % UPDATE FIGURES
            % -- mean
            tabMean = array2table(app.meanPComp_win);
            colNames = string(1:length(app.meanPComp_win));
            app.MeanTable.Data = tabMean;
            app.MeanTable.ColumnName = colNames;

            % -- variance
            tabVar = array2table(app.varPComp_win);
            colNames = string(1:length(app.varPComp_win));
            app.VarianceTable.Data = tabVar;
            app.VarianceTable.ColumnName = colNames;

            % -- Number of components
            app.NumberofComponentsEditField.Value = app.numOfComps_win;
        end

        function updateAnalysisPlotsAndFigures(app)
            % Fill NaN if the checkbox is active
            meanPComp_sig = app.meanPComp_sig;
            varPComp_sig = app.varPComp_sig;

            if app.FillNaNCheckBox.Value
                meanPComp_sig(isnan(meanPComp_sig))=0;
                varPComp_sig(isnan(varPComp_sig))=0;
            end
            

            % number of components is the number of plots for each the mean
            % and the variance
            maxNumComps = max(app.numOfComps_sig);
            numPlots = maxNumComps +1;
            n = 2*numPlots+1;
            if strcmp(app.FaultDetectionSwitch.Value, 'On')
                n = n+1;
            end

            % delete existing plots if necessary
            ax = matlab.graphics.axis.Axes.empty();

            while(length(app.DevelopmentofKeyFiguresPanel.Children)>0)
                delete(app.DevelopmentofKeyFiguresPanel.Children(end))
            end

            % set plot settings
            plot_height = 0.3;
            tile_space = 0.1;
            offset = 0.05;

            % plot Number of Components
            app.DevelopmentofKeyFiguresPanel.AutoResizeChildren = 'off';
            ax(1) = subplot(n,1,1,'Parent',app.DevelopmentofKeyFiguresPanel);
            pos_og = get(ax(1), 'Position');
            left_og = pos_og(1);
            width_og = pos_og(3);

            % offset = bottom_og - height_og;
            new_position = [left_og, offset + (n-1)*plot_height+(n-1)*tile_space, width_og, plot_height];
            set(ax(1), 'Position', new_position);

            plot(ax(1), app.numOfComps_sig);
            title(ax(1), 'Number of Components per Window');
            xlim(ax(1),[1,length(app.numOfComps_sig)]);

            % plot Mean Per Components
            for i=2:1+numPlots
                ax(i) = subplot(n,1,i,'Parent',app.DevelopmentofKeyFiguresPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                plot(ax(i), meanPComp_sig(i-1,:));
                if i==1+numPlots
                    subtitle(ax(i), 'Residual')
                else
                    subtitle(ax(i), ['Component ',num2str(i-1)]);
                end
                xlim(ax(i),[1,length(app.numOfComps_sig)]);
                if i==2
                    title(ax(i),'Mean per Component');
                end
            end

            % plot Variance Per Components
            for i=2+numPlots:1+2*numPlots
                ax(i) = subplot(n,1,i,'Parent',app.DevelopmentofKeyFiguresPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                plot(ax(i), varPComp_sig(i-1-numPlots,:));
                if i==1+2*numPlots
                    subtitle(ax(i), 'Residual')
                else
                    subtitle(ax(i), ['Component ',num2str(i-1-numPlots)]);
                end
                xlim(ax(i),[1,length(app.numOfComps_sig)]);
                if i==2+numPlots
                    title(ax(i),'Variance per Component');
                end
            end

            if strcmp(app.FaultDetectionSwitch.Value, 'On')
                i = 2+2*numPlots;
                ax(i) = subplot(n,1,i,'Parent',app.DevelopmentofKeyFiguresPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                unique_classes = unique(app.faultDetectFlags, 'stable');
                y_values = zeros(size(app.faultDetectFlags));
                x_values = 1:1:length(app.faultDetectFlags);
                for k = 1:length(unique_classes)
                    y_values(strcmp(app.faultDetectFlags, unique_classes(k))) = k;
                end
                scatter(ax(i),x_values, y_values, 'filled');
                yticks(ax(i),1:length(unique_classes));
                yticklabels(ax(i),unique_classes);
                title(ax(i),'Fault Detection');
            end
        end

        function updateSpectroPanel(app)
            % create tiled layout for plots
            tllyData = tiledlayout(app.SignalSpectrogramPanel,1,1);

            x = table2array(app.uploadedData(:,app.DataTable.Selection));
            fs = int32(app.SamplingFrequencyEditField.Value);
                                  
            nt = nexttile(tllyData);
                        

            % Calculate spectrogram
            w = int32(app.LengthEditField.Value); %window
            no = int32(w/2); %n overlap 
 
            % Create figure and plot spectrogram
            [S, F, T] = spectrogram(x,w,no,[],fs);
            
            imagesc(nt, T, F, abs(S),[min(min(abs(S))) max(max(abs(S)))]);
                
           
            c = colorbar(nt);
            
            %Color bar legend
            c.Label.String = 'Power/Frequency (Db/Hz)'; 
            
            ylabel(nt, 'Frequency (Hz)')
            xlabel(nt, 'Time (s)');
            title(nt, 'Spectrogram');
           
        end

       function updateComponentSpectrogram(app)
            n = size(app.stftPComp_win,2); % Get the number of components to know how many spectrograms to plot
            
            % remove old plots
            ax = matlab.graphics.axis.Axes.empty();
            while(length(app.SpectrogramperComponentPanel.Children)>0)
                delete(app.SpectrogramperComponentPanel.Children(end))
            end

            % plot settings
            plot_height = 0.7;
            tile_space = 0.1;
            offset = 0.1;

            % plot first one
            app.SpectrogramperComponentPanel.AutoResizeChildren = 'off';
            ax(1) = subplot(n,1,1,'Parent',app.SpectrogramperComponentPanel);
            pos_og = get(ax(1), 'Position');
            left_og = pos_og(1);
            width_og = pos_og(3);

            %offset = bottom_og - height_og;
            new_position = [left_og, offset + (n-1)*plot_height+(n-1)*tile_space, width_og, plot_height];
            set(ax(1), 'Position', new_position);

            s = app.stftPComp_win(:,1);
            f = app.freqs_win;
            t = [1];
    
            % plot spectrogram
            imagesc(ax(1), t, f, abs(s), [min(min(abs(s))), max(max(abs(s)))]);

            c = colorbar(ax(1));
            c.Label.String = 'Power/Frequency (dB/Hz)';
    
            % label axes and title
            ylabel(ax(1), 'Frequency (Hz)');
            xlabel(ax(1), 'Time (s)');
            subtitle(ax(1), 'Component 1');

            % plot all additional ones
            for (i=2:n)
                ax(i) = subplot(n,1,i,'Parent',app.SpectrogramperComponentPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                s = app.stftPComp_win(:,i);
                f = app.freqs_win;
                t = [1];
        
                % Plot spectrogram
                imagesc(ax(i), t, f, abs(s), [min(min(abs(s))), max(max(abs(s)))]);
    
                c = colorbar(ax(i));
                c.Label.String = 'Power/Frequency (dB/Hz)';
        
                % Label axes and title
                ylabel(ax(i), 'Frequency (Hz)');
                xlabel(ax(i), 'Time (s)');
                if i==n
                    subtitle(ax(i), 'Residual')
                else
                    subtitle(ax(i), ['Component ',num2str(i)]);
                end
            end
       end

       
       function updateComponentSpectrogramAnalysis(app)
            n = length(app.stftPComp_sig); % Get the number of components to know how many spectrograms to plot
            
            % remove old plots
            ax = matlab.graphics.axis.Axes.empty();
            while(length(app.DevelopmentofComponentSpectrogramsPanel.Children)>0)
                delete(app.DevelopmentofComponentSpectrogramsPanel.Children(end))
            end

            % plot settings
            plot_height = 0.7;
            tile_space = 0.1;
            offset = 0.1;

            % plot first one
            app.DevelopmentofComponentSpectrogramsPanel.AutoResizeChildren = 'off';
            ax(1) = subplot(n,1,1,'Parent',app.DevelopmentofComponentSpectrogramsPanel);
            pos_og = get(ax(1), 'Position');
            left_og = pos_og(1);
            width_og = pos_og(3);

            %offset = bottom_og - height_og;
            new_position = [left_og, offset + (n-1)*plot_height+(n-1)*tile_space, width_og, plot_height];
            set(ax(1), 'Position', new_position);

            s = app.stftPComp_sig{1};
            f = app.freqs_sig;
            t = [1];
    
            % plot spectrogram
            imagesc(ax(1), t, f, abs(s), [min(min(abs(s))), max(max(abs(s)))]);

            c = colorbar(ax(1));
            c.Label.String = 'Power/Frequency (dB/Hz)';
    
            % label axes and title
            ylabel(ax(1), 'Frequency (Hz)');
            xlabel(ax(1), 'Time (s)');
            subtitle(ax(1), 'Component 1');

            % plot all additional ones
            for (i=2:n)
                ax(i) = subplot(n,1,i,'Parent',app.DevelopmentofComponentSpectrogramsPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                s = app.stftPComp_sig{i};
        
                % Plot spectrogram
                imagesc(ax(i), t, f, abs(s), [min(min(abs(s))), max(max(abs(s)))]);
    
                c = colorbar(ax(i));
                c.Label.String = 'Power/Frequency (dB/Hz)';
        
                % Label axes and title
                ylabel(ax(i), 'Frequency (Hz)');
                xlabel(ax(i), 'Time (s)');
                if i==n
                    subtitle(ax(i), 'Residual')
                else
                    subtitle(ax(i), ['Component ',num2str(i)]);
                end
            end
        end

        
        function faultdetection(app)
            if ~isempty(app.analysisData)
                signal = table2array(app.analysisData); 
                classIndex = faultDetectionWindow(signal);
                
                % Check for a valid class index
                if classIndex == -1
                    return;  % Handle invalid class index (e.g., signal length issue or server error)
                end
                
                % Update the lamp color for binary healthy/faulty indication
                if strcmp(classIndex, 'healthy')
                    app.FaultDetectionLamp.Color = [0 1 0]; % Green
                else
                    app.FaultDetectionLamp.Color = [1 0 0]; % Red
                end
    
                % Update multiclass label
                switch classIndex
                    case 'faulty'
                        app.FaultDetectionMulticlassLabel.Text = 'faulty';
                    case 'healthy'
                        app.FaultDetectionMulticlassLabel.Text = 'healthy signal';
                    case 'impulse'
                        app.FaultDetectionMulticlassLabel.Text = 'impulse';
                    case 'additional component'
                        app.FaultDetectionMulticlassLabel.Text = 'additional component';
                    case 'frequency modulation'
                        app.FaultDetectionMulticlassLabel.Text = 'frequency modulation';
                    case 'amplitude modulation'
                        app.FaultDetectionMulticlassLabel.Text = 'amplitude modulation';
                    case 'frequency and amplitude modulation'
                        app.FaultDetectionMulticlassLabel.Text = 'frequency and amplitude modulation';
                    case 'impulse and additional component'
                        app.FaultDetectionMulticlassLabel.Text = 'impulse and additional component';
                    case 'impulse and frequency modulation'
                        app.FaultDetectionMulticlassLabel.Text = 'impulse and frequency modulation';
                    case 'impulse and amplitude modulation'
                        app.FaultDetectionMulticlassLabel.Text = 'impulse and amplitude modulation';
                    case 'impulse, frequency and amplitude modulation'
                        app.FaultDetectionMulticlassLabel.Text = 'impulse, frequency and amplitude modulation';
                end
            end
        end
        
        function continuousProcess(app)
            % start/ stop widgets
            app.DataTable.Visible = "on";
            app.DataVisualizationPanel.Visible="on";
            app.PositionSlider.Enable = "off"; % during the process the slider is off
            app.PositionSlider.Value = 1;

            % settings
            WINDOW_SIZE = app.LengthEditField.Value; % Size of the window
            SAMPLING_FREQUENCY = app.SamplingFrequencyEditField.Value; % Amount of samples per second
            SIGNAL_TO_NOISE_RATIO = 0;
            getFaultDetection = strcmp(app.FaultDetectionSwitch.Value,"On");

            number_of_minutes = app.MonitoringTimeEditField.Value;
            total_number_of_seconds = number_of_minutes * 60;
            
            total_samples = total_number_of_seconds * SAMPLING_FREQUENCY;
            time = linspace(0, total_number_of_seconds, total_samples);

            if total_samples < WINDOW_SIZE
                warndlg('The monitoring interval must have a length that contains at least one window')
            elseif WINDOW_SIZE~=1000 && getFaultDetection
                warndlg('You can only use fault detection for a window length of 1000.')
            else
                % generator
                random_state = randi(2^31) - 1;
                generator = RealisticGenerator(SAMPLING_FREQUENCY, SIGNAL_TO_NOISE_RATIO, [], [], [], random_state, true);
                
                % save calculated values in the following variables
                winIdx = 1; %index of the window
                maxNumComps = 1; % calculate number of plots iteratively

                meanPComp_sig = NaN(1,1); % mean per component
                varPComp_sig = NaN(1,1); % variance per component
                stftPComp_sig = cell(1,1); % stft per component, we get one matrix per component

                meanRes_sig = NaN(1,1); % mean of residual
                varRes_sig = NaN(1,1); % variance of residual
                stftRes_sig = cell(1,1); % stft of residual
                faultDetectFlags = [""];
                numOfComps_sig = [];

                % iterate over windows
                for i = 1:WINDOW_SIZE:length(time)-WINDOW_SIZE+1
                    % generate window
                    window_time = time(i:i+WINDOW_SIZE-1);
                    data = generator.generate_dataset(1, window_time(1), window_time(end)); % this is our new data window
                    data = data.faulty_signals.'; % convert it into columns

                    % save data 
                    if winIdx==1
                        app.uploadedData = array2table(data);
                    else
                        app.uploadedData = vertcat(app.uploadedData, array2table(data));
                        if size(app.uploadedData,1)>100000
                            app.uploadedData = app.uploadedData(1:100000,:);
                        end
                    end

                    app.analysisData = array2table(data); % add it to the dataframe
                    [m,n] = size(app.uploadedData);
                    app.DataTable.Data = app.uploadedData;
                    app.DataTable.ColumnName = "Column" + [1:1:n];
                    app.DataTable.Selection=1;

                    % Visualization
                    if winIdx>=2
                        UpdateRangeFcn(app); % update the range
                        app.PositionSlider.Value = app.PositionSlider.Limits(2);
                    end
                    updateDataPlots(app); % update the data plots
                    if(strcmp(app.FaultDetectionSwitch.Value,'On'))
                        faultdetection(app);
                    end
                    % -- Calculation --
                    % get SSD components
                    comps = SSD(data, SAMPLING_FREQUENCY, app.ThresholdEditField.Value); 
                    comps = comps.';

                    % calculate the values per window
                    [numOfComps,meanPComp,varPComp,stftPComp,freqs] = keyFigsWindow(comps,SAMPLING_FREQUENCY, WINDOW_SIZE);
                    numOfComps_sig(winIdx) = numOfComps;
                    if numOfComps > maxNumComps 
                        diff = numOfComps - maxNumComps;
                        % if one window has more components than the
                        % previous ones, add NaN rows
                        numDP = size(meanPComp_sig,2);
                        nanVecHor = NaN(diff, numDP);
                        zeroMat = zeros(length(freqs),numDP);

                        % add NaN rows
                        meanPComp_sig(maxNumComps+1:numOfComps,:) = nanVecHor;
                        varPComp_sig(maxNumComps+1:numOfComps,:) = nanVecHor;

                        % add zero matrices for stft for new components
                        for l=maxNumComps:1:numOfComps
                            stftPComp_sig{l} = zeroMat;
                        end

                        maxNumComps = numOfComps;
                    end

                    nanVecVert = NaN(maxNumComps, 1);

                    % add NaN column at the end
                    meanPComp_sig(:,winIdx) = nanVecVert;
                    varPComp_sig(:,winIdx) = nanVecVert;

                    % fill with values
                    meanPComp_sig(1:numOfComps,winIdx) = meanPComp(1:numOfComps);
                    varPComp_sig(1:numOfComps,winIdx) = varPComp(1:numOfComps);
                    for l = 1:1:maxNumComps
                        if l <= numOfComps
                            % if component with index n exists, add frequencys to the
                            % matrix
                            stftPComp_sig{l} = [stftPComp_sig{l} stftPComp(:,l)];
                        else
                            stftPComp_sig{l} = [stftPComp_sig{l} zeros(size(stftPComp,1),1)];
                        end
                    end

                    meanRes_sig(winIdx) = meanPComp(end);
                    varRes_sig(winIdx) = varPComp(end);

                    stftRes_sig{1} = [stftRes_sig{1} stftPComp(:,end)];
                    
                    % if fault detection is activated, get the response
                    % from the NN
                    if getFaultDetection
                        faultDetectFlags(winIdx) = string(faultDetectionWindow(data));
                    end
                    
                    app.numOfComps_sig = numOfComps_sig;
                    app.meanPComp_sig = meanPComp_sig;
                    app.meanPComp_sig(end+1,:) = meanRes_sig;

                    app.varPComp_sig = varPComp_sig;
                    app.varPComp_sig(end+1,:) = varRes_sig;

                    app.stftPComp_sig = stftPComp_sig;
                    app.stftPComp_sig{end+1} = stftRes_sig{1};

                    app.freqs_sig = freqs;
                    app.numOfComps_sig = numOfComps_sig;
                    
                    app.faultDetectFlags = faultDetectFlags;

                    if winIdx >=2
                        updateAnalysisPlotsAndFigures(app);
                        updateComponentSpectrogramAnalysis(app);
                    end

                    % todo: select last window
                    % todo: calculate values and prediction and visualize
                    % them 
                    pause(2) % pause for 2 seconds
                    winIdx = winIdx+1;
                end
                app.PositionSlider.Enable = "on"; % after the generation process the slider is active again
                app.UpdateButton.Enable = "on";
                % After the process is over 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Determine the path to the directory containing server.py
           % assuming that server.py is located close to mlapp
            addpath(genpath('../'))
            serverDir = fullfile(pwd); 
            
            % Move to the server directory
            cd(serverDir);
                      
            % Run the server in the background
            use_realistic_data = strcmp(app.ModelSwitch.Value, 'Advanced');
            command = ['python "..\server.py" ', char(string(use_realistic_data)), ' &'];

            [status, result] = system(command);
        end

        % Value changed function: PositionSlider
        function sliderChangedFcn(app, event)
          if ~isempty(app.uploadedData) 
                % guarantee an integer value on the slider
                value = round(app.PositionSlider.Value);
                app.PositionSlider.Value = value;
                start_idx = 1+ (int32(round(app.PositionSlider.Value)-1) * round(app.StepsizeEditField.Value));
                end_idx = int32(start_idx + round(app.LengthEditField.Value))-1;
                app.analysisData = app.uploadedData(start_idx:end_idx, app.DataTable.Selection);
                updateDataPlots(app);
    
                % calculate Components and visualize
                calcSSDAndFigs(app);
                updateComponentPlotsAndFigues(app);
                % updateSpectroPanel(app);
                updateComponentSpectrogram(app);
    
                if(strcmp(app.FaultDetectionSwitch.Value,'On'))
                    faultdetection(app);
                end
            else
                warndlg('Please upload Data first!');
            end
        end

        % Button pushed function: UploaddataButton
        function UploadButtenPushedFcn(app, event)
            % Read file
            [file, path] = uigetfile('*.csv');
            figure(app.UIFigure);
            if ischar(file) && ischar(path)  % Check if a file is selected
                fullFilePath = fullfile(path, file);  % Construct the full file path

                % Load data
                app.uploadedData = readtable(fullFilePath);
                [m,n] = size(app.uploadedData);
                app.DataTable.Data = app.uploadedData;
                app.DataTable.ColumnName = "Column" + [1:1:n];

                % Make Data visualization visible
                app.DataTable.Visible = "on";
                app.DataVisualizationPanel.Visible="on";
                app.DataTable.Selection = 1;

                % Set the limits for the window parameters and the slider
                if ~strcmp(app.FaultDetectionSwitch.Value, 'On')
                    app.LengthEditField.Value = round(0.2*m);
                end
                app.LengthEditField.Limits = [2 m-1];
                UpdateRangeFcn(app,event);
                app.UpdateButton.Enable = "on";
                app.FaultDetectionSwitch.Enable = "on";
                app.StartAnalysisButton.Enable = "on";
            else
                % Handle case when no file is selected or user cancels the dialog
                disp('No file selected or operation canceled.');
            end

            if(strcmp(app.FaultDetectionSwitch.Value,'On'))
                faultdetection(app);
            end

            
        end

        % Button pushed function: UpdateButton
        function UpdateRangeFcn(app, event)
            % Update the limits for the window parameters and the slider
            [m,n] = size(app.uploadedData);
            
            app.PositionSlider.Value = 1;
            app.PositionSlider.Limits(2) = int32(floor((m - app.LengthEditField.Value) / app.StepsizeEditField.Value)) + 1;
            start_idx = 1+ (int32(round(app.PositionSlider.Value)-1) * round(app.StepsizeEditField.Value));
            end_idx = int32(start_idx + round(app.LengthEditField.Value))-1;
            app.analysisData = app.uploadedData(start_idx:end_idx, app.DataTable.Selection);
            app.StepsizeEditField.Limits(2) = m-app.LengthEditField.Value;
            updateDataPlots(app);
            
            % calculate Components and visualize
            calcSSDAndFigs(app);
            updateComponentPlotsAndFigues(app);
            updateSpectroPanel(app);
            updateComponentSpectrogram(app)
        end

        % Value changed function: ZoominwindowCheckBox
        function ZoominwindowValueChangedFcn(app, event)
            updateDataPlots(app);
        end

        % Selection changed function: DataTable
        function DataColumnSelectionChangedFcn(app, event)
            if ~isempty(app.uploadedData)
                start_idx = 1+(int32(round(app.PositionSlider.Value)-1) * round(app.StepsizeEditField.Value));
                end_idx = int32(start_idx + round(app.LengthEditField.Value))-1;
                app.analysisData = app.uploadedData(start_idx:end_idx, app.DataTable.Selection);
                updateDataPlots(app);
    
                % calculate Components and visualize
                calcSSDAndFigs(app);
                updateComponentPlotsAndFigues(app);
                updateSpectroPanel(app);
                updateComponentSpectrogram(app)
                if(strcmp(app.FaultDetectionSwitch.Value,'On'))
                    faultdetection(app);
                end
            else
                warndlg('Please upload Data first!');
            end
        end

        % Button pushed function: StartAnalysisButton
        function AnalysisButtonPushedFcn(app, event)
            if ~isempty(app.uploadedData)
                signal = table2array(app.uploadedData(:, app.DataTable.Selection));
                if strcmp(app.FaultDetectionSwitch.Value,'On')
                    [app.numOfComps_sig,app.meanPComp_sig,app.varPComp_sig, app.stftPComp_sig, app.freqs_sig, app.faultDetectFlags] = keyFigsSignal(signal, app.LengthEditField.Value, app.StepsizeEditField.Value, app.SamplingFrequencyEditField.Value,app.ThresholdEditField.Value, true);
                else
                    [app.numOfComps_sig,app.meanPComp_sig,app.varPComp_sig, app.stftPComp_sig, app.freqs_sig, app.faultDetectFlags] = keyFigsSignal(signal, app.LengthEditField.Value, app.StepsizeEditField.Value, app.SamplingFrequencyEditField.Value,app.ThresholdEditField.Value, false);
                end
                updateAnalysisPlotsAndFigures(app);
                updateComponentSpectrogramAnalysis(app);
            else
                warndlg('Please upload Data first!');
            end
            
        end

        % Value changed function: FillNaNCheckBox
        function FillNaNValueChangedFcn(app, event)
            if ~isempty(app.numOfComps_sig)
                updateAnalysisPlotsAndFigures(app);
            end
        end

        % Value changed function: FaultDetectionSwitch
        function FaultDetectionSwitchValueChangedFcn(app, event)
            value = app.FaultDetectionSwitch.Value;
            if(strcmp(value,'On'))
                app.LengthEditField.Value = 1000;
                app.LengthEditField.Enable = "off";
                if ~isempty(app.uploadedData)
                    UpdateRangeFcn(app,event);
                    faultdetection(app);
                end
            else
                app.LengthEditField.Enable = "on";
                app.FaultDetectionLamp.Color = [0.8 0.8 0.8];
                app.FaultDetectionMulticlassLabel.Text = 'Fault Name';
            end
        end

        % Button pushed function: StartContinuousProcessButton
        function ContinuousProcessButtonPushedFcn(app, event)
            continuousProcess(app)
            if(strcmp(app.FaultDetectionSwitch.Value,'On'))
                faultdetection(app);
            end
        end

        % Value changed function: ModelSwitch
        function ModelSwitchValueChangedFcn(app, event)
            startupFcn(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.LengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.LengthEditField.Limits = [2 Inf];
            app.LengthEditField.ValueDisplayFormat = '%g';
            app.LengthEditField.Value = 100;
            app.WindowLabel = uilabel(app.SettingsPanel);
            app.WindowLabel.Text = 'Window:';
            app.VisualizationLabel = uilabel(app.SettingsPanel);
            app.VisualizationLabel.Text = 'Visualization:';
            app.ZoominwindowCheckBox = uicheckbox(app.SettingsPanel);
            app.ZoominwindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoominwindowValueChangedFcn, true);
            app.ZoominwindowCheckBox.Text = 'Zoom in window';
            app.UpdateButton = uibutton(app.SettingsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRangeFcn, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Update the slider range'};
            app.UpdateButton.Text = 'Update';
            app.StepsizeLabel = uilabel(app.SettingsPanel);
            app.StepsizeLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.StepsizeEditField.Limits = [1 Inf];
            app.StepsizeEditField.Value = 1;
            app.PositionSlider_2Label = uilabel(app.SettingsPanel);
            app.PositionSlider_2Label.Text = 'Position';
            app.PositionSlider = uislider(app.SettingsPanel);
            app.PositionSlider.Limits = [1 100];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @sliderChangedFcn, true);
            app.PositionSlider.MinorTicks = [];
            app.PositionSlider.Value = 1;
            app.SingularSpectrumDecompositionLabel = uilabel(app.SettingsPanel);
            app.SingularSpectrumDecompositionLabel.Text = 'Singular Spectrum Decomposition:';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.SettingsPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SamplingFrequencyEditField.Limits = [1 Inf];
            app.SamplingFrequencyEditField.Tooltip = {'Number of samples per second'};
            app.SamplingFrequencyEditField.Value = 5120;
            app.ThresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Value = 0.005;
            app.FillNaNCheckBox = uicheckbox(app.SettingsPanel);
            app.FillNaNCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillNaNValueChangedFcn, true);
            app.FillNaNCheckBox.Text = 'Fill NaN';
            app.FillNaNCheckBox.Value = true;
            app.SwitchLabel = uilabel(app.SettingsPanel);
            app.SwitchLabel.Text = '';
            app.FaultDetectionSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.FaultDetectionSwitch.ValueChangedFcn = createCallbackFcn(app, @FaultDetectionSwitchValueChangedFcn, true);
            app.FaultDetectionLabel = uilabel(app.SettingsPanel);
            app.FaultDetectionLabel.Text = 'Fault Detection:';
            app.ContinuousProcessLabel = uilabel(app.SettingsPanel);
            app.ContinuousProcessLabel.Text = 'Continuous Process:';
            app.MonitoringTimeminLabel = uilabel(app.SettingsPanel);
            app.MonitoringTimeminLabel.Text = 'Monitoring Time [min]';
            app.MonitoringTimeEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MonitoringTimeEditField.Limits = [2 Inf];
            app.MonitoringTimeEditField.ValueDisplayFormat = '%g';
            app.MonitoringTimeEditField.Value = 20;
            app.ModelSwitchLabel = uilabel(app.SettingsPanel);
            app.ModelSwitchLabel.Text = 'Model';
            app.ModelSwitch = uiswitch(app.SettingsPanel, 'rocker');
            app.ModelSwitch.Items = {'Simple', 'Advanced'};
            app.ModelSwitch.ValueChangedFcn = createCallbackFcn(app, @ModelSwitchValueChangedFcn, true);
            app.ModelSwitch.Value = 'Simple';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout3 = uigridlayout(app.DataTab);
            app.UploaddataButton = uibutton(app.GridLayout3, 'push');
            app.UploaddataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtenPushedFcn, true);
            app.UploaddataButton.Text = 'Upload data';
            app.DataTable = uitable(app.GridLayout3);
            app.DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DataTable.RowName = {};
            app.DataTable.SelectionType = 'column';
            app.DataTable.SelectionChangedFcn = createCallbackFcn(app, @DataColumnSelectionChangedFcn, true);
            app.DataTable.Visible = 'off';
            app.DataVisualizationPanel = uipanel(app.GridLayout3);
            app.DataVisualizationPanel.Title = 'Visualization';
            app.DataVisualizationPanel.Visible = 'off';
            app.DataSpectrogramTab = uitab(app.TabGroup);
            app.DataSpectrogramTab.Title = 'Data Spectrogram';
            app.GridLayout5 = uigridlayout(app.DataSpectrogramTab);
            app.SignalSpectrogramPanel = uipanel(app.GridLayout5);
            app.SignalSpectrogramPanel.Title = 'Signal Spectrogram';
            app.ComponentsTab = uitab(app.TabGroup);
            app.ComponentsTab.Title = 'Components';
            app.GridLayout2 = uigridlayout(app.ComponentsTab);
            app.SSDComponentsPanel = uipanel(app.GridLayout2);
            app.SSDComponentsPanel.Title = 'SSD Components';
            app.KeyFiguresPanel = uipanel(app.GridLayout2);
            app.KeyFiguresPanel.Title = 'Key Figures';
            app.NumberofComponentsLabel = uilabel(app.KeyFiguresPanel);
            app.NumberofComponentsLabel.Text = 'Number of Components:';
            app.NumberofComponentsEditField = uieditfield(app.KeyFiguresPanel, 'numeric');
            app.NumberofComponentsEditField.Editable = 'off';
            app.MeanTable = uitable(app.KeyFiguresPanel);
            app.MeanTable.ColumnName = {'Component 1'; 'Component 2'; 'Component 3'};
            app.MeanTable.RowName = {};
            app.MeanLabel = uilabel(app.KeyFiguresPanel);
            app.MeanLabel.Text = 'Mean:';
            app.VarianceTable = uitable(app.KeyFiguresPanel);
            app.VarianceTable.ColumnName = {'Component 1'; 'Component 2'; 'Component 3'};
            app.VarianceTable.RowName = {};
            app.VarianceLabel = uilabel(app.KeyFiguresPanel);
            app.VarianceLabel.Text = 'Variance:';
            app.ComponentSpectrogramsTab = uitab(app.TabGroup);
            app.ComponentSpectrogramsTab.Title = 'Component Spectrograms';
            app.GridLayout6 = uigridlayout(app.ComponentSpectrogramsTab);
            app.SpectrogramperComponentPanel = uipanel(app.GridLayout6);
            app.SpectrogramperComponentPanel.Title = 'Spectrogram per Component';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.GridLayout4 = uigridlayout(app.AnalysisTab);
            app.DevelopmentofKeyFiguresPanel = uipanel(app.GridLayout4);
            app.DevelopmentofKeyFiguresPanel.Title = 'Development of Key Figures';
            app.SpectrogramAnalysisTab = uitab(app.TabGroup);
            app.SpectrogramAnalysisTab.Title = 'Spectrogram Analysis';
            app.DevelopmentofComponentSpectrogramsPanel = uipanel(app.SpectrogramAnalysisTab);
            app.DevelopmentofComponentSpectrogramsPanel.Title = 'Development of Component Spectrograms';
            app.StartAnalysisButton = uibutton(app.GridLayout, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushedFcn, true);
            app.StartAnalysisButton.Enable = 'off';
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.FaultDetectionLampLabel = uilabel(app.GridLayout);
            app.FaultDetectionLampLabel.Text = 'Fault Detection:';
            app.FaultDetectionLamp = uilamp(app.GridLayout);
            app.StartContinuousProcessButton = uibutton(app.GridLayout, 'push');
            app.StartContinuousProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuousProcessButtonPushedFcn, true);
            app.StartContinuousProcessButton.Text = 'Start Continuous Process';
            app.FaultDetectionMulticlassLabel = uilabel(app.GridLayout);
            app.FaultDetectionMulticlassLabel.Text = 'Fault Name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSDApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end