classdef SSDApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        StartAnalysisButton          matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        DataTab                      matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        DataVisualizationPanel       matlab.ui.container.Panel
        DataTable                    matlab.ui.control.Table
        UploaddataButton             matlab.ui.control.Button
        SpectrogramAnalysisTab       matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        SignalSpectrogramPanel       matlab.ui.container.Panel
        ComponentsTab                matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        KeyFiguresPanel              matlab.ui.container.Panel
        VarianceLabel                matlab.ui.control.Label
        VarianceTable                matlab.ui.control.Table
        MeanLabel                    matlab.ui.control.Label
        MeanTable                    matlab.ui.control.Table
        NumberofComponentsEditField  matlab.ui.control.NumericEditField
        NumberofComponentsLabel      matlab.ui.control.Label
        ComponentsPanel              matlab.ui.container.Panel
        ComponentsSpectrogramsTab    matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        ComponentSpectrogramsPanel   matlab.ui.container.Panel
        AnalysisTab                  matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        KeyFiguresAnalysisPanel      matlab.ui.container.Panel
        SettingsPanel                matlab.ui.container.Panel
        FillNaNCheckBox              matlab.ui.control.CheckBox
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        SamplingFrequencyEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SingularSpectrumDecompositionLabel  matlab.ui.control.Label
        PositionSlider               matlab.ui.control.Slider
        PositionSlider_2Label        matlab.ui.control.Label
        StepsizeEditField            matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel       matlab.ui.control.Label
        UpdateButton                 matlab.ui.control.Button
        ZoominwindowCheckBox         matlab.ui.control.CheckBox
        VisualizationLabel           matlab.ui.control.Label
        WindowLabel                  matlab.ui.control.Label
        LengthEditField              matlab.ui.control.NumericEditField
        LengthEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        uploadedData % raw data that is uploaded
        analysisData % data that is currently used for the analysis
        analysisSsdComponents % calculated SSD components for the current window
        numOfComps_win % calculated number of components for the current window
        meanPComp_win % calculated mean per component for the current window
        varPComp_win % calculated variance per component for the current window
        numOfComps_sig % calculated number of components for the whole signal
        meanPComp_sig % calculated mean per component for the whole signal
        varPComp_sig % calculated variance per component for the whole signal
    end
    
    methods (Access = private)
        
        function updateDataPlots(app)
            [m,n] = size(app.uploadedData);

            % create tiled layout for plots
            tllyData = tiledlayout(app.DataVisualizationPanel,1,1);

            selected_column_index = app.DataTable.Selection;
            % if zoom function is off, mark the range with vertical lines
            if (~app.ZoominwindowCheckBox.Value)
                % get indices for vertical lines
                start_idx = 1+ (app.PositionSlider.Value-1) * app.StepsizeEditField.Value;
                end_idx = start_idx + app.LengthEditField.Value-1;

                % plot the selected signal
                ax = nexttile(tllyData);
                plot(ax, table2array(app.uploadedData(:,selected_column_index)));
                hold(ax, 'on');
                xline(ax, start_idx, Color='red', LineWidth=0.7);
                xline(ax, end_idx, Color='red', LineWidth=0.7);
                xlim(ax,[1,m]);
                tllyData.TileSpacing = 'compact';
                tllyData.Padding = 'compact';
                hold(ax, 'off');
            else
                % plot data to analyze 
                [m,n] = size(app.analysisData);
                ax = nexttile(tllyData);
                plot(ax, table2array(app.analysisData));
                xlim(ax,[1,m]);
                tllyData.TileSpacing = 'compact';
                tllyData.Padding = 'compact';
            end
        end
        
        function calcSSDAndFigs(app)
            comps = SSD(table2array(app.analysisData),app.SamplingFrequencyEditField.Value, app.ThresholdEditField.Value);
            app.analysisSsdComponents = comps.';
            [app.numOfComps_win,app.meanPComp_win,app.varPComp_win] = keyFigsWindow(comps.');
        end
        
        function updateComponentPlotsAndFigues(app)
            % UPDATE PLOTS
            [m,n] = size(app.analysisSsdComponents);
            
            ax = matlab.graphics.axis.Axes.empty();
            for k=flip(1:length(app.ComponentsPanel.Children))
                delete(app.ComponentsPanel.Children(k))
            end

            plot_height = 0.4;
            tile_space = 0.1;
            offset = 0.05;

            app.ComponentsPanel.AutoResizeChildren = 'off';
            ax(1) = subplot(n,1,1,'Parent',app.ComponentsPanel);
            pos_og = get(ax(1), 'Position');
            left_og = pos_og(1);
            width_og = pos_og(3);

            %offset = bottom_og - height_og;
            new_position = [left_og, offset + (n-1)*plot_height+(n-1)*tile_space, width_og, plot_height];
            set(ax(1), 'Position', new_position);

            plot(ax(1), app.analysisSsdComponents(:,1));
            xlim(ax(1),[1,m]);
            subtitle(ax(1), 'Component 1');

            for (i=2:n)
                ax(i) = subplot(n,1,i,'Parent',app.ComponentsPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                plot(ax(i), app.analysisSsdComponents(:,i));
                xlim(ax(i),[1,m]);
                subtitle(ax(i), ['Component ',num2str(i)]);
            end

            % UPDATE FIGURES
            % -- mean
            tabMean = array2table(app.meanPComp_win);
            colNames = string(1:length(app.meanPComp_win));
            app.MeanTable.Data = tabMean;
            app.MeanTable.ColumnName = colNames;

            % -- variance
            tabVar = array2table(app.varPComp_win);
            colNames = string(1:length(app.varPComp_win));
            app.VarianceTable.Data = tabVar;
            app.VarianceTable.ColumnName = colNames;

            % -- Number of components
            app.NumberofComponentsEditField.Value = app.numOfComps_win;
        end

        function updateAnalysisPlotsAndFigures(app)
            % Fill NaN if the checkbox is active
            meanPComp_sig = app.meanPComp_sig;
            varPComp_sig = app.varPComp_sig;

            if app.FillNaNCheckBox.Value
                meanPComp_sig(isnan(meanPComp_sig))=0;
                varPComp_sig(isnan(varPComp_sig))=0;
            end
            

            % number of components is the number of plots for each the mean
            % and the variance
            maxNumComps = max(app.numOfComps_sig);
            n = 2*max(app.numOfComps_sig)+1;

            % delete existing plots if necessary
            ax = matlab.graphics.axis.Axes.empty();

            for k=flip(1:length(app.KeyFiguresAnalysisPanel.Children))
                delete(app.KeyFiguresAnalysisPanel.Children(k))
            end

            % set plot settings
            plot_height = 0.3;
            tile_space = 0.1;
            offset = 0.05;

            % plot Number of Components
            app.KeyFiguresAnalysisPanel.AutoResizeChildren = 'off';
            ax(1) = subplot(n,1,1,'Parent',app.KeyFiguresAnalysisPanel);
            pos_og = get(ax(1), 'Position');
            left_og = pos_og(1);
            width_og = pos_og(3);

            % offset = bottom_og - height_og;
            new_position = [left_og, offset + (n-1)*plot_height+(n-1)*tile_space, width_og, plot_height];
            set(ax(1), 'Position', new_position);

            plot(ax(1), app.numOfComps_sig);
            title(ax(1), 'Number of Components per Window');
            xlim(ax(1),[1,length(app.numOfComps_sig)]);

            % plot Mean Per Components
            for i=2:1+maxNumComps
                ax(i) = subplot(n,1,i,'Parent',app.KeyFiguresAnalysisPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                plot(ax(i), meanPComp_sig(:,i-1));
                subtitle(ax(i), ['Component ',num2str(i-1)]);
                xlim(ax(i),[1,length(app.numOfComps_sig)]);
                if i==2
                    title(ax(i),'Mean per Component');
                end
            end

            % plot Variance Per Components
            for i=2+maxNumComps:1+2*maxNumComps
                ax(i) = subplot(n,1,i,'Parent',app.KeyFiguresAnalysisPanel);
                new_position = [left_og, offset + (n-i)*plot_height+(n-i)*tile_space,width_og, plot_height];
                set(ax(i), 'Position', new_position);
                plot(ax(i), varPComp_sig(:,i-1-maxNumComps));
                subtitle(ax(i), ['Component ',num2str(i-1-maxNumComps)]);
                xlim(ax(i),[1,length(app.numOfComps_sig)]);
                if i==2+maxNumComps
                    title(ax(i),'Variance per Component');
                end
            end
            
        end


        function updateSpectroPanel(app)
            % create tiled layout for plots
            tllyData = tiledlayout(app.SignalSpectrogramPanel,1,1);

            x = table2array(app.analysisData);
            fs = int16(double(app.SamplingFrequencyEditField));
                                  
            nt = nexttile(tllyData);
                        

            % Calculate spectrogram
            w = int16(double(app.LengthEditField)); %window
            no = int16(w/2); %n overlap

            % Create figure and plot spectrogram
            [S, F, T] = spectrogram(x,w,no,[],fs);
            
            imagesc(nt, T, F, abs(S),[min(min(abs(S))) max(max(abs(S)))]);
                
           
            c = colorbar(nt);
            
            %Color bar legend
            c.Label.String = 'Power/Frequency (Db/Hz)'; 
            
            ylabel(nt, 'Frequency (Hz)')
            xlabel(nt, 'Time (s)');
            title(nt, 'Spectrogram');
           
        end 

       function updateComponentSpectrogram(app)
            [~, n] = size(app.analysisSsdComponents); % Get the number of components to know how many spectrograms to plot
        
            % Calculate the number of rows and columns for the layout
            rows = ceil(sqrt(n));
            cols = ceil(n / rows);

        
            % Create tiled layout for plots
            tllyData = tiledlayout(app.ComponentSpectrogramsPanel, rows, cols);
        
            % Plot spectrogram for each component
            for i = 1:n
                % Get data for the current component
                component_data = app.analysisSsdComponents(:, i);
        
                % Calculate spectrogram for the component data
                fs = double(app.SamplingFrequencyEditField.Value);
                w = double(app.LengthEditField.Value); % Window size
                no = w / 2; % Overlap
                % S: STFT , F: frequency, T: time vector
                [S, F, T] = spectrogram(component_data, w, no, [], fs);
        
                % Create a new tile for the spectrogram
                ax = nexttile(tllyData);
        
                % Plot spectrogram
                imagesc(ax, T, F, abs(S), [min(min(abs(S))), max(max(abs(S)))]);
                hold(ax, 'on');
                % Add colorbar
                c = colorbar(ax);
                c.Label.String = 'Power/Frequency (dB/Hz)';
        
                % Label axes and title
                ylabel(ax, 'Frequency (Hz)')
                xlabel(ax, 'Time (s)');
                title(ax, ['Spectrogram - Component ', num2str(i)]);
                hold(ax, 'off');
            end
        
            % Adjust layout
            tllyData.TileSpacing = 'compact';
            tllyData.Padding = 'compact';
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PositionSlider
        function sliderChangedFcn(app, event)
            % guarantee an integer value on the slider
            value = round(app.PositionSlider.Value);
            app.PositionSlider.Value = value;
            start_idx = 1+ (int32(round(app.PositionSlider.Value)-1) * round(app.StepsizeEditField.Value));
            end_idx = int32(start_idx + round(app.LengthEditField.Value))-1;
            app.analysisData = app.uploadedData(start_idx:end_idx, app.DataTable.Selection);
            updateDataPlots(app);

            % calculate Components and visualize
            calcSSDAndFigs(app);
            updateComponentPlotsAndFigues(app);
            updateSpectroPanel(app);
            updateComponentSpectrogram(app); 
        end

        % Button pushed function: UploaddataButton
        function UploadButtenPushedFcn(app, event)
            % Read file
            [file, path] = uigetfile('*.csv');
            figure(app.UIFigure);
            if ischar(file) && ischar(path)  % Check if a file is selected
                fullFilePath = fullfile(path, file);  % Construct the full file path
                            
                % Load data
                app.uploadedData = readtable(fullFilePath);
                app.DataTable.Data = app.uploadedData;

                % Make Data visualization visible
                app.DataTable.Visible = "on";
                app.DataVisualizationPanel.Visible="on";
                app.DataTable.Selection = 1;

                % Set the limits for the window parameters and the slider
                [m,n] = size(app.uploadedData);
                app.LengthEditField.Value = round(0.2*m);
                UpdateRangeFcn(app,event);

            else
                % Handle case when no file is selected or user cancels the dialog
                disp('No file selected or operation canceled.');
            end
        end

        % Button pushed function: UpdateButton
        function UpdateRangeFcn(app, event)
            % Update the limits for the window parameters and the slider
            [m,n] = size(app.uploadedData);
            
            app.PositionSlider.Value = 1;
            app.PositionSlider.Limits(2) = int32(floor((m - app.LengthEditField.Value) / app.StepsizeEditField.Value)) + 1;
            start_idx = 1+ (int32(round(app.PositionSlider.Value)-1) * round(app.StepsizeEditField.Value));
            end_idx = int32(start_idx + round(app.LengthEditField.Value))-1;
            app.analysisData = app.uploadedData(start_idx:end_idx, app.DataTable.Selection);
            updateDataPlots(app);
            
            % calculate Components and visualize
            calcSSDAndFigs(app);
            updateComponentPlotsAndFigues(app);
            updateSpectroPanel(app);
            updateComponentSpectrogram(app)
        end

        % Value changed function: ZoominwindowCheckBox
        function ZoominwindowValueChangedFcn(app, event)
            updateDataPlots(app);
        end

        % Selection changed function: DataTable
        function DataColumnSelectionChangedFcn(app, event)
            start_idx = 1+(int32(round(app.PositionSlider.Value)-1) * round(app.StepsizeEditField.Value));
            end_idx = int32(start_idx + round(app.LengthEditField.Value))-1;
            app.analysisData = app.uploadedData(start_idx:end_idx, app.DataTable.Selection);
            updateDataPlots(app);

            % calculate Components and visualize
            calcSSDAndFigs(app);
            updateComponentPlotsAndFigues(app);
            updateSpectroPanel(app);
            updateComponentSpectrogram(app)
        end

        % Button pushed function: StartAnalysisButton
        function AnalysisButtonPushedFcn(app, event)
            if ~isempty(app.uploadedData)
                signal = table2array(app.uploadedData(:, app.DataTable.Selection));
                [app.numOfComps_sig,app.meanPComp_sig,app.varPComp_sig] = keyFigsSignal(signal, app.LengthEditField.Value, app.StepsizeEditField.Value, app.SamplingFrequencyEditField.Value,app.ThresholdEditField.Value);
                updateAnalysisPlotsAndFigures(app);
            else
                warndlg('Please upload Data first!');
            end
            
        end

        % Value changed function: FillNaNCheckBox
        function FillNaNValueChangedFcn(app, event)
            updateAnalysisPlotsAndFigures(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.LengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.LengthEditField.Limits = [1 Inf];
            app.LengthEditField.ValueDisplayFormat = '%g';
            app.LengthEditField.Value = 100;
            app.WindowLabel = uilabel(app.SettingsPanel);
            app.WindowLabel.Text = 'Window:';
            app.VisualizationLabel = uilabel(app.SettingsPanel);
            app.VisualizationLabel.Text = 'Visualization:';
            app.ZoominwindowCheckBox = uicheckbox(app.SettingsPanel);
            app.ZoominwindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoominwindowValueChangedFcn, true);
            app.ZoominwindowCheckBox.Text = 'Zoom in window';
            app.UpdateButton = uibutton(app.SettingsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRangeFcn, true);
            app.UpdateButton.Text = 'Update';
            app.StepsizeEditFieldLabel = uilabel(app.SettingsPanel);
            app.StepsizeEditFieldLabel.Text = 'Stepsize';
            app.StepsizeEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.StepsizeEditField.Limits = [1 Inf];
            app.StepsizeEditField.Value = 1;
            app.PositionSlider_2Label = uilabel(app.SettingsPanel);
            app.PositionSlider_2Label.Text = 'Position';
            app.PositionSlider = uislider(app.SettingsPanel);
            app.PositionSlider.Limits = [1 100];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @sliderChangedFcn, true);
            app.PositionSlider.MinorTicks = [];
            app.PositionSlider.Value = 1;
            app.SingularSpectrumDecompositionLabel = uilabel(app.SettingsPanel);
            app.SingularSpectrumDecompositionLabel.Text = 'Singular Spectrum Decomposition:';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.SettingsPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SamplingFrequencyEditField.Limits = [1 Inf];
            app.SamplingFrequencyEditField.Value = 1000;
            app.ThresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Value = 0.005;
            app.FillNaNCheckBox = uicheckbox(app.SettingsPanel);
            app.FillNaNCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillNaNValueChangedFcn, true);
            app.FillNaNCheckBox.Text = 'Fill NaN';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout3 = uigridlayout(app.DataTab);
            app.UploaddataButton = uibutton(app.GridLayout3, 'push');
            app.UploaddataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtenPushedFcn, true);
            app.UploaddataButton.Text = 'Upload data';
            app.DataTable = uitable(app.GridLayout3);
            app.DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DataTable.RowName = {};
            app.DataTable.SelectionType = 'column';
            app.DataTable.SelectionChangedFcn = createCallbackFcn(app, @DataColumnSelectionChangedFcn, true);
            app.DataTable.Visible = 'off';
            app.DataVisualizationPanel = uipanel(app.GridLayout3);
            app.DataVisualizationPanel.Title = 'Visualization';
            app.DataVisualizationPanel.Visible = 'off';
            app.SpectrogramAnalysisTab = uitab(app.TabGroup);
            app.SpectrogramAnalysisTab.Title = 'Spectrogram Analysis';
            app.GridLayout5 = uigridlayout(app.SpectrogramAnalysisTab);
            app.SignalSpectrogramPanel = uipanel(app.GridLayout5);
            app.SignalSpectrogramPanel.Title = 'Signal Spectrogram';
            app.ComponentsTab = uitab(app.TabGroup);
            app.ComponentsTab.Title = 'Components';
            app.GridLayout2 = uigridlayout(app.ComponentsTab);
            app.ComponentsPanel = uipanel(app.GridLayout2);
            app.ComponentsPanel.Title = 'Components';
            app.KeyFiguresPanel = uipanel(app.GridLayout2);
            app.KeyFiguresPanel.Title = 'Key Figures';
            app.NumberofComponentsLabel = uilabel(app.KeyFiguresPanel);
            app.NumberofComponentsLabel.Text = 'Number of Components:';
            app.NumberofComponentsEditField = uieditfield(app.KeyFiguresPanel, 'numeric');
            app.NumberofComponentsEditField.Editable = 'off';
            app.MeanTable = uitable(app.KeyFiguresPanel);
            app.MeanTable.ColumnName = {'Component 1'; 'Component 2'; 'Component 3'};
            app.MeanTable.RowName = {};
            app.MeanLabel = uilabel(app.KeyFiguresPanel);
            app.MeanLabel.Text = 'Mean:';
            app.VarianceTable = uitable(app.KeyFiguresPanel);
            app.VarianceTable.ColumnName = {'Component 1'; 'Component 2'; 'Component 3'};
            app.VarianceTable.RowName = {};
            app.VarianceLabel = uilabel(app.KeyFiguresPanel);
            app.VarianceLabel.Text = 'Variance:';
            app.ComponentsSpectrogramsTab = uitab(app.TabGroup);
            app.ComponentsSpectrogramsTab.Title = 'Components Spectrograms';
            app.GridLayout6 = uigridlayout(app.ComponentsSpectrogramsTab);
            app.ComponentSpectrogramsPanel = uipanel(app.GridLayout6);
            app.ComponentSpectrogramsPanel.Title = 'Component Spectrograms';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.GridLayout4 = uigridlayout(app.AnalysisTab);
            app.KeyFiguresAnalysisPanel = uipanel(app.GridLayout4);
            app.KeyFiguresAnalysisPanel.Title = 'Key Figures Analysis';
            app.StartAnalysisButton = uibutton(app.GridLayout, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushedFcn, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSDApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end